classdef wipApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        TabGroup                    matlab.ui.container.TabGroup
        BandpassTab                 matlab.ui.container.Tab
        GridLayout2_2               matlab.ui.container.GridLayout
        EditField_5                 matlab.ui.control.NumericEditField
        EditField_4                 matlab.ui.control.NumericEditField
        DropDown_3                  matlab.ui.control.DropDown
        ImpulseresponseLabel_2      matlab.ui.control.Label
        Slider_4                    matlab.ui.control.Slider
        Slider_3                    matlab.ui.control.Slider
        SteepnessUpperboundLabel_2  matlab.ui.control.Label
        SteepnessLowerboundLabel_2  matlab.ui.control.Label
        UpperboundLabel_2           matlab.ui.control.Label
        LowerboundLabel_2           matlab.ui.control.Label
        RMSwindowTab                matlab.ui.container.Tab
        GridLayout3_5               matlab.ui.container.GridLayout
        EditField_6                 matlab.ui.control.NumericEditField
        WindowsizeLabel_2           matlab.ui.control.Label
        ExportPanel_2               matlab.ui.container.Panel
        GridLayout3_4               matlab.ui.container.GridLayout
        ExportsettingsButton_2      matlab.ui.control.Button
        ExportdataButton_2          matlab.ui.control.Button
        OutlierremovalPanel         matlab.ui.container.Panel
        GridLayout3_2               matlab.ui.container.GridLayout
        DropDown_2                  matlab.ui.control.DropDown
        StandarddeviationsLabel     matlab.ui.control.Label
        FileinputPanel              matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        selectfileButton            matlab.ui.control.Button
        MCVfileLabel                matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        processing_track = "bandpass selected";
        filepath = "";
    end
    
    methods (Access = private)
        
        function ProcessEverything(app)
            if app.filepath ~= ""
                % get all parameters
                lowerbound = app.EditField_4.Value;
                upperbound = app.EditField_5.Value;
                rmswindowsize = app.EditField_6.Value;
                std = app.DropDown_2.Value;
                lowerboundsteepness = app.Slider_3.Value;
                upperboundsteepness = app.Slider_4.Value;
                response = app.DropDown_3.Value;
                disp(app.processing_track);
                app.ExportPanel_2.Enable;

                % start processing
                ProcessStep1(app);
                ProcessTrackAB(app);
            end
        end
        
        function ProcessStep1(app)
            
        end

        function ProcessTrackAB(app)
            
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: selectfileButton
        function FileSelect(app, event)

            try
                [file,path] = uigetfile('*.txt')  %open a file
                app.filepath = string(path)+string(file);
            catch error
                disp(error)
            end

            % after file was succesfully selected enable all the parameters
            lowerbound = app.EditField_4.Enable;
            upperbound = app.EditField_5.Enable;
            rmswindowsize = app.EditField_6.Enable;
            std = app.DropDown_2.Value;
            lowerboundsteepness = app.Slider_3.Enable;
            upperboundsteepness = app.Slider_4.Enable;
            response = app.DropDown_3.Enable;
            
            ProcessEverything(app);
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            ProcessEverything(app);
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            ProcessEverything(app);
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            ProcessEverything(app);
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            ProcessEverything(app);
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            ProcessEverything(app);
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            ProcessEverything(app);
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            ProcessEverything(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            if exist(path, 'file')
                if app.TabGroup.SelectedTab == app.BandpassTab
                    app.processing_track = "bandpass selected";
                else
                    app.processing_track = "rms selected";
                end
            end
            ProcessEverything(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {600, 600};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {270, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FileinputPanel = uipanel(app.LeftPanel);
            app.FileinputPanel.Title = 'File input';
            app.GridLayout3 = uigridlayout(app.FileinputPanel);
            app.MCVfileLabel = uilabel(app.GridLayout3);
            app.MCVfileLabel.Text = 'MCV file:';
            app.selectfileButton = uibutton(app.GridLayout3, 'push');
            app.selectfileButton.ButtonPushedFcn = createCallbackFcn(app, @FileSelect, true);
            app.selectfileButton.Text = 'select file';
            app.OutlierremovalPanel = uipanel(app.LeftPanel);
            app.OutlierremovalPanel.Title = 'Outlier removal';
            app.GridLayout3_2 = uigridlayout(app.OutlierremovalPanel);
            app.StandarddeviationsLabel = uilabel(app.GridLayout3_2);
            app.StandarddeviationsLabel.Text = 'Standard deviations';
            app.DropDown_2 = uidropdown(app.GridLayout3_2);
            app.DropDown_2.Items = {'1', '2', '3'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Tag = 'std';
            app.DropDown_2.Enable = 'off';
            app.DropDown_2.Value = '1';
            app.ExportPanel_2 = uipanel(app.LeftPanel);
            app.ExportPanel_2.Enable = 'off';
            app.ExportPanel_2.Title = 'Export';
            app.GridLayout3_4 = uigridlayout(app.ExportPanel_2);
            app.ExportdataButton_2 = uibutton(app.GridLayout3_4, 'push');
            app.ExportdataButton_2.Text = 'Export data';
            app.ExportsettingsButton_2 = uibutton(app.GridLayout3_4, 'push');
            app.ExportsettingsButton_2.Text = 'Export settings';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.BandpassTab = uitab(app.TabGroup);
            app.BandpassTab.Title = 'Bandpass';
            app.GridLayout2_2 = uigridlayout(app.BandpassTab);
            app.LowerboundLabel_2 = uilabel(app.GridLayout2_2);
            app.LowerboundLabel_2.Text = 'Lowerbound';
            app.UpperboundLabel_2 = uilabel(app.GridLayout2_2);
            app.UpperboundLabel_2.Text = 'Upperbound';
            app.SteepnessLowerboundLabel_2 = uilabel(app.GridLayout2_2);
            app.SteepnessLowerboundLabel_2.Text = {'Steepness'; 'Lowerbound'};
            app.SteepnessUpperboundLabel_2 = uilabel(app.GridLayout2_2);
            app.SteepnessUpperboundLabel_2.Text = {'Steepness'; 'Upperbound'};
            app.Slider_3 = uislider(app.GridLayout2_2);
            app.Slider_3.Limits = [0 1];
            app.Slider_3.MajorTicks = [0 0.25 0.5 0.75 1];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.Tag = 'steepnesslower';
            app.Slider_3.Enable = 'off';
            app.Slider_3.Value = 0.85;
            app.Slider_4 = uislider(app.GridLayout2_2);
            app.Slider_4.Limits = [0 1];
            app.Slider_4.MajorTicks = [0 0.25 0.5 0.75 1];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_4.Tag = 'steepnessupper';
            app.Slider_4.Enable = 'off';
            app.Slider_4.Value = 0.85;
            app.ImpulseresponseLabel_2 = uilabel(app.GridLayout2_2);
            app.ImpulseresponseLabel_2.Text = 'Impulse response';
            app.DropDown_3 = uidropdown(app.GridLayout2_2);
            app.DropDown_3.Items = {'auto', 'iir', 'fir'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Tag = 'impulse';
            app.DropDown_3.Enable = 'off';
            app.DropDown_3.Value = 'auto';
            app.EditField_4 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Tag = 'lowerbound';
            app.EditField_4.Enable = 'off';
            app.EditField_5 = uieditfield(app.GridLayout2_2, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_5.Tag = 'upperbound';
            app.EditField_5.Enable = 'off';
            app.RMSwindowTab = uitab(app.TabGroup);
            app.RMSwindowTab.Title = 'RMS window';
            app.GridLayout3_5 = uigridlayout(app.RMSwindowTab);
            app.WindowsizeLabel_2 = uilabel(app.GridLayout3_5);
            app.WindowsizeLabel_2.Text = 'Window size';
            app.EditField_6 = uieditfield(app.GridLayout3_5, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_6.Tag = 'rmswindow';
            app.EditField_6.Enable = 'off';
            app.EditField_6.Value = 5;
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wipApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end